classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        MainTab                  matlab.ui.container.Tab
        GDPNowcastingAppLabel    matlab.ui.control.Label
        FilesPanel               matlab.ui.container.Panel
        SampleDataButton         matlab.ui.control.Button
        SampleSpecButton         matlab.ui.control.Button
        LoadDataFileButton       matlab.ui.control.Button
        SpecFileButton           matlab.ui.control.Button
        FilesPanelLabel          matlab.ui.control.Label
        SpecStatusLabel          matlab.ui.control.Label
        DataStatusLabel          matlab.ui.control.Label
        ConfigureRun             matlab.ui.container.Panel
        FilterScrollPanel        matlab.ui.container.Panel
        DeselectAllButton        matlab.ui.control.Button
        SelectAllButton          matlab.ui.control.Button
        CheckboxScrollPanel      matlab.ui.container.Panel
        SeriesFilterNoteLabel    matlab.ui.control.Label
        SeriesFilterLabel        matlab.ui.control.Label
        ConfigPanelLabel         matlab.ui.control.Label
        RunModelButton           matlab.ui.control.Button
        ResultsTab               matlab.ui.container.Tab
        ExportExcelButton        matlab.ui.control.Button
        NewsTableLabel           matlab.ui.control.Label
        NowcastPanel             matlab.ui.container.Panel
        NowcastUnitsLabel        matlab.ui.control.Label
        NowcastValueLabel        matlab.ui.control.Label
        NowcastTitleLabel        matlab.ui.control.Label
        NewsTable                matlab.ui.control.Table
        ReleasePanel             matlab.ui.container.Panel
        ReleaseSubLabel          matlab.ui.control.Label
        ReleaseLabel             matlab.ui.control.Label
        RevisionPanel            matlab.ui.container.Panel
        RevisionSubLabel         matlab.ui.control.Label
        RevisionLabel            matlab.ui.control.Label
        PrevVintagePanel         matlab.ui.container.Panel
        PrevVintageDateLabel     matlab.ui.control.Label
        PrevVintageLabel         matlab.ui.control.Label
        FiguresTab               matlab.ui.container.Tab
        TabGroup2                matlab.ui.container.TabGroup
        Fig1Tab                  matlab.ui.container.Tab
        Fig1SeriesDropdown       matlab.ui.control.DropDown
        Fig1SeriesDropdownLabel  matlab.ui.control.Label
        Fig1AxesTransformed      matlab.ui.control.UIAxes
        Fig1AxesRaw              matlab.ui.control.UIAxes
        Fig2Tab                  matlab.ui.container.Tab
        Fig2Axes                 matlab.ui.control.UIAxes
        Fig3Tab                  matlab.ui.container.Tab
        Fig3SeriesDropDown       matlab.ui.control.DropDown
        DropDown2Label           matlab.ui.control.Label
        Fig3AxesBottom           matlab.ui.control.UIAxes
        Fig3AxesTop              matlab.ui.control.UIAxes
        SpecCompTab              matlab.ui.container.Tab
        SpecCompDiff             matlab.ui.control.UIAxes
        SpecCompOverlay          matlab.ui.control.UIAxes
        NewsTab                  matlab.ui.container.Tab
        NewsAxes                 matlab.ui.control.UIAxes
        AICopilotTab             matlab.ui.container.Tab
        SummaryTable             matlab.ui.container.Tab
        TextArea_4               matlab.ui.control.TextArea
        TextArea                 matlab.ui.control.TextArea
        FullLogLabel             matlab.ui.control.Label
        ShortSummaryLabel        matlab.ui.control.Label
        RunSummaryTableButton    matlab.ui.control.Button
    end


    %-------------------------------------------------------------------------------------

    % Properties Created
    properties (Access = private)
        DataFilePath
        SpecFilePath

        OriginalTable

        TimeOriginal
        TimeUpdated

        Spec

        OriginalResults
        UpdatedResults

        OriginalLog
        UpdatedLog

        ModifiedSpec
        CheckboxHandles = {}
        IncludedSeries = []

        XData

        NowcastValue %current GDP nowcast value
        NowcastQuarter % fiscal quarter
        SpecFile            char
        DataFile            char
        BlockCheckboxes % array of CheckBox handles, built by populateFilterPanel
        SeriesCheckboxes cell


    end
    %------------------------------------------------------------------------------------

    % custom METHODS
    methods (Access = private)
        %------------------------------------------------------------------------------------------

        % Load SPEC
        %   Loads the USERS SPEC file into Model
        %   Lets USER know if the file has load successfully or not
        function loadSpecFile(app)
            [file, path] = uigetfile({'*.xls;*.xlsx','Excel Files (*.xls, *.xlsx)'}, ...
                'Select Specification File');

            if isequal(file,0)
                return
            end

            app.SpecFilePath = fullfile(path, file);

            try
                app.Spec = load_spec(app.SpecFilePath);
                populateFilterPanel(app);
                app.SpecStatusLabel.Text            = ['✓ ' file];
                app.SpecStatusLabel.FontColor       = [0.13 0.55 0.13];
                populateFiguresDropdowns(app);
            catch ME
                app.SpecStatusLabel.Text            = '✗ Load failed';
                app.SpecStatusLabel.FontColor       = [0.85 0.10 0.10];
                uialert(app.UIFigure, ME.message, 'Spec Load Error');
            end
        end


        %------------------------------------------------------------------------------------------

        % Load DATA
        %   Loads the USERS DATA file into Model
        %   Lets USER know if the file has load successfully or not
        function loadDataFile(app)
            [file, path] = uigetfile({'*.xls;*.xlsx','Excel Files (*.xls, *.xlsx)'}, ...
                'Select Data File');

            if isequal(file,0)
                return
            end

            app.DataFilePath = fullfile(path, file);

            try
                T = readtable(app.DataFilePath, 'Sheet', 'data');
                app.OriginalTable = T;
                app.DataStatusLabel.Text      = ['✓ ' file];
                app.DataStatusLabel.FontColor = [0.13 0.55 0.13];
            catch ME
                app.DataStatusLabel.Text      = '✗ Load failed';
                app.DataStatusLabel.FontColor = [0.85 0.10 0.10];
                uialert(app.UIFigure, ME.message, 'Data Load Error');
            end
        end


        %------------------------------------------------------------------------------------------

        function runModel(app)

            % Check that files are loaded first
            if isempty(app.SpecFilePath) || isempty(app.DataFilePath)
                uialert(app.UIFigure, ...
                    'Please load both a Spec file and a Data file first.', ...
                    'Missing Files');
                return
            end

            try

                sampleStart = datenum('2000-01-01', 'yyyy-mm-dd');

                % Load spec if not already loaded
                if isempty(app.Spec)
                    app.Spec = load_spec(app.SpecFilePath);
                end

                % Load data once. loadExcel=1 bypasses the .mat cache.
                [X, Time, Z] = load_data(app.DataFilePath, app.Spec, sampleStart, 1);
                app.XData = Z;   % store raw data for Fig1
                % apply current toggle state to produce ModifiedSpec
                buildModifiedSpec(app);

                % Run model on original spec
                app.OriginalLog = evalc([ ...
                    'disp("Running original model...");' ...
                    'app.OriginalResults = dfm(X, app.Spec, threshold);' ...
                    ]);


                % Run model on modified spec. Skip if nothing toggled off
                if isequal(app.Spec.Blocks, app.ModifiedSpec.Blocks)
                    app.UpdatedResults = app.OriginalResults;
                    app.UpdatedLog = app.OriginalLog;
                else
                    app.UpdatedLog = evalc([ ...
                        'disp("Running modified model...");' ...
                        'app.UpdatedResults = dfm(X, app.ModifiedSpec, threshold);' ...
                        ]);
                end


                % Single time vector shared by both models
                app.TimeOriginal = Time;
                app.TimeUpdated  = Time;

            catch ME
                uialert(app.UIFigure, ...
                    ['Model run failed: ' ME.message], ...
                    'Run Error');
            end
        end

        %------------------------------------------------------------------------------------------
        % computeGDPProjection
        %   comptutes GDP

        function y = computeGDPProjection(app, Res, spec)
            %Find GDPC1 in spec
            gdp_idx = find(strcmp('GDPC1', spec.SeriesID));

            if isempty(gdp_idx)
                %Spec does not contain GDPC1, use X_sm(:,1) instead
                warning('GDPC1 not found in Spec. Plotting kalman smoothed series 1 instead.');
                y = Res.X_sm(:,1);
                return
            end

            % Number of current-period factors = total factors across all blocks
            nF = size(spec.Blocks, 2);

            % Common factor projection in original unstandardized units
            % Using formula from FRBNY example_DFM.m lines 74-75
            y = Res.C(gdp_idx, 1:nF) * Res.Z(:,1:nF)' ...
                .*Res.Wx(gdp_idx) + Res.Mx(gdp_idx);

            % transpose column vector to match Time
            y = y(:);


        end


        %------------------------------------------------------------------------------------------
        % PopulateFilterPanel
        function populateFilterPanel(app)

            Spec    = app.Spec;
            nBlocks = size(Spec.Blocks, 2);

            % --- Clear existing contents ---
            delete(app.FilterScrollPanel.Children);
            app.BlockCheckboxes  = matlab.ui.control.CheckBox.empty(1,0);
            app.SeriesCheckboxes = cell(nBlocks, 1);

            % --- Count total rows ---
            rowH  = 24;
            nRows = 0;
            for b = 1:nBlocks
                nRows = nRows + 1 + sum(Spec.Blocks(:, b) == 1);
            end

            % --- Scrollable grid — the grid IS the scroller ---
            % Scrollable = 'on' on the grid, not the panel.
            % Fixed px row heights give the grid a concrete intrinsic height
            % larger than the panel, which activates the scrollbar.
            g = uigridlayout(app.FilterScrollPanel, ...
                'RowHeight',     repmat({rowH}, 1, nRows), ...
                'ColumnWidth',   {'1x'}, ...
                'RowSpacing',    0, ...
                'ColumnSpacing', 0, ...
                'Padding',       [4 4 4 4], ...
                'BackgroundColor', [0.97 0.97 0.97], ...
                'Scrollable',    'on');          % <-- ON THE GRID, not the panel

            % --- Populate rows ---
            rowIdx = 0;
            for b = 1:nBlocks
                seriesIdxInBlock = find(Spec.Blocks(:, b) == 1);
                blockName        = Spec.BlockNames{b};

                % Block header checkbox
                rowIdx = rowIdx + 1;
                bCB = uicheckbox(g, ...
                    'Text',       ['▶  ' blockName], ...
                    'Value',      1, ...
                    'FontWeight', 'bold', ...
                    'FontSize',   12, ...
                    'FontColor',  [0.24 0.20 0.54]);
                bCB.Layout.Row    = rowIdx;
                bCB.Layout.Column = 1;
                bCB.UserData        = b;
                bCB.ValueChangedFcn = @(src,evt) onBlockCheckboxChanged(app, src, evt);
                app.BlockCheckboxes(b) = bCB;

                % Series checkboxes
                sCBs = matlab.ui.control.CheckBox.empty(1,0);
                for si = 1:numel(seriesIdxInBlock)
                    sIdx   = seriesIdxInBlock(si);
                    rowIdx = rowIdx + 1;
                    label  = ['        ' Spec.SeriesID{sIdx} '  –  ' Spec.SeriesName{sIdx}];
                    sCB = uicheckbox(g, ...
                        'Text',     label, ...
                        'Value',    1, ...
                        'FontSize', 11);
                    sCB.Layout.Row    = rowIdx;
                    sCB.Layout.Column = 1;
                    sCB.UserData        = [b, sIdx];
                    sCB.ValueChangedFcn = @(src,evt) onSeriesCheckboxChanged(app, src, evt);
                    sCBs(si) = sCB;
                end
                app.SeriesCheckboxes{b} = sCBs;
            end
        end
        %------------------------------------------------------------------------------------------

        function onBlockCheckboxChanged(app, src, ~)
            b = src.UserData;           % block index
            newVal = src.Value;
            sCBs = app.SeriesCheckboxes{b};
            for i = 1:numel(sCBs)
                sCBs(i).Value = newVal;
            end
        end
        %------------------------------------------------------------------------------------------

        function onSeriesCheckboxChanged(app, src, ~)
            b = src.UserData(1);        % block index (first element of UserData)
            updateBlockHeaderState(app, b);
        end

        %------------------------------------------------------------------------------------------

        function updateBlockHeaderState(app, b)
            % Set block checkbox: checked if all series on, unchecked if all off,
            % visually "mixed" (FontColor change) if partial.
            sCBs = app.SeriesCheckboxes{b};
            vals = arrayfun(@(c) c.Value, sCBs);
            bCB  = app.BlockCheckboxes(b);

            if all(vals)
                bCB.Value     = 1;
                bCB.FontColor = [0.24 0.20 0.54];   % full purple = all on
            elseif ~any(vals)
                bCB.Value     = 0;
                bCB.FontColor = [0.24 0.20 0.54];
            else
                % Partial: keep checkbox checked but signal mixed state with gray text
                bCB.Value     = 1;
                bCB.FontColor = [0.55 0.55 0.55];   % gray = indeterminate
            end
        end

        %------------------------------------------------------------------------------------------

        % buildModifiedSpec
        function ModSpec = buildModifiedSpec(app)
            % Reads checkbox state and constructs a filtered Spec.
            % Series with unchecked boxes are removed (row deletion).
            % Block columns that become all-zero are dropped.
            % GDP series is always kept regardless of checkbox state.

            ModSpec = app.Spec;     % start from full spec
            nSeries = numel(ModSpec.SeriesID);
            nBlocks = size(ModSpec.Blocks, 2);

            % --- Identify GDP row (never removed) ---
            gdpID = 'GDPC1';
            gdpRow = find(strcmpi(ModSpec.SeriesID, gdpID), 1);

            % --- Collect excluded series indices from checkboxes ---
            excludeRows = false(nSeries, 1);
            for b = 1:nBlocks
                sCBs = app.SeriesCheckboxes{b};
                seriesIdxInBlock = find(app.Spec.Blocks(:, b) == 1);
                for si = 1:numel(sCBs)
                    if sCBs(si).Value == 0
                        absIdx = seriesIdxInBlock(si);
                        if absIdx ~= gdpRow   % protect GDP
                            excludeRows(absIdx) = true;
                        end
                    end
                end
            end

            keepRows = ~excludeRows;

            % --- Filter all Spec fields that are per-series ---
            seriesFields = {'SeriesID','SeriesName','Frequency','Units', ...
                'Transformation','UnitsTransformed','Category','Blocks'};
            for f = 1:numel(seriesFields)
                fld = seriesFields{f};
                if isfield(ModSpec, fld)
                    val = ModSpec.(fld);
                    if size(val, 1) == nSeries
                        ModSpec.(fld) = val(keepRows, :);
                    end
                end
            end

            % --- Drop block columns that are now entirely zero ---
            emptyBlocks = ~any(ModSpec.Blocks, 1);  % columns with no loaded series

            % Block 1 (global) must never be dropped — enforce this
            emptyBlocks(1) = false;

            ModSpec.Blocks     = ModSpec.Blocks(:, ~emptyBlocks);
            ModSpec.BlockNames = ModSpec.BlockNames(~emptyBlocks);
        end
        %------------------------------------------------------------------------------------------
        % computeBlockContributions
        function computeBlockContributions(app)
            gdp_idx = find(strcmp('GDPC1', app.Spec.SeriesID));

            if isempty(gdp_idx)
                warning('GDPC1 not found in Spec.');
                gdp_idx = 1;
            end

            nF = size(app.Spec.Blocks, 2);
            Wx_gdp = app.OriginalResults.Wx(gdp_idx);
            contributions = zeros(nF, 1);

            for i = 1:nF
                orig = app.OriginalResults.C(gdp_idx, i) .* app.OriginalResults.Z(:,i) .* Wx_gdp;
                mod  = app.UpdatedResults.C(gdp_idx, i)  .* app.UpdatedResults.Z(:,i)  .* Wx_gdp;
                contributions(i) = mean(orig - mod, 'omitnan');
            end
        end

        %------------------------------------------------------------------------------------------

        % updateNewsChart

        %------------------------------------------------------------------------------------------

        % EXPORT
        %   Exports Model results
        function exportResults(app)
            [file, path] = uiputfile({'*.xlsx','Excel Workbook (*.xlsx)'}, ...
                'Save Exported Results');

            if isequal(file,0)
                return
            end

            fullFile = fullfile(path, file);

            try
                if ~isempty(app.OriginalResults)
                    % Use actual series names as column headers
                    colNames = matlab.lang.makeValidName(app.Spec.SeriesName);
                    origTable = array2table(app.OriginalResults.X_sm, ...
                        'VariableNames', colNames);
                    writetable(origTable, fullFile, 'Sheet', 'OriginalSmoothedSeries');
                end

                if ~isempty(app.UpdatedResults)
                    colNames = matlab.lang.makeValidName(app.ModifiedSpec.SeriesName);
                    updTable = array2table(app.UpdatedResults.X_sm, ...
                        'VariableNames', colNames);
                    writetable(updTable, fullFile, 'Sheet', 'ModifiedSmoothedSeries');
                end

                uialert(app.UIFigure, 'Export successful.', 'Export Complete', 'Icon', 'success');
            catch ME
                uialert(app.UIFigure, ME.message, 'Export Error');
            end
        end
        %------------------------------------------------------------------------------------------
        % Turn DFM results into text and place into Text Area
        %   Comment in displayResultsInTextArea that the left text area is intended
        %   for the short comparison summary and the right text area is intended for the full detailed log.
        function displayResultsInTextArea(app)

            if isempty(app.OriginalResults) || isempty(app.UpdatedResults)
                uialert(app.UIFigure, 'Run the model first.', 'No Results');
                return
            end

            try
                % -----------------------------
                % Example summary values
                % -----------------------------
                origRows = height(app.OriginalTable);
                origCols = width(app.OriginalTable);

                editRows = origRows;  % placeholder — ModifiedSpec row count not yet computed
                editCols = origCols;  % placeholder — ModifiedSpec col count not yet computed


                % Example using first smoothed series / factor
                origMean = mean(app.OriginalResults.X_sm(:,1), 'omitnan');
                editMean = mean(app.UpdatedResults.X_sm(:,1), 'omitnan');
                diffMean = editMean - origMean;

                % -----------------------------
                % Combined summary box
                % -----------------------------
                summaryLines = {
                    'SUMMARY COMPARISON'
                    '------------------'
                    ['Original rows loaded: ' num2str(origRows)]
                    ['Modified spec rows: ' num2str(editRows)]
                    ' '
                    ['Original columns loaded: ' num2str(origCols)]
                    ['Modified spec columns: ' num2str(editCols)]
                    ' '
                    ['Original Series 1 mean: ' num2str(origMean, '%.4f')]
                    ['Edited Series 1 mean: ' num2str(editMean, '%.4f')]
                    ['Difference: ' num2str(diffMean, '%.4f')]
                    };

                app.TextArea.Value = summaryLines;

                % -----------------------------
                % Full combined log box
                % -----------------------------
                fullLog = [
                    "===== ORIGINAL MODEL OUTPUT ====="
                    splitlines(string(app.OriginalLog))
                    ""
                    "===== EDITED MODEL OUTPUT ====="
                    splitlines(string(app.UpdatedLog))
                    ];

                app.TextArea_4.Value = cellstr(fullLog);

            catch ME
                uialert(app.UIFigure, ...
                    ['Could not display results: ' ME.message], ...
                    'Display Error');
            end
        end

        %------------------------------------------------------------------------------------------
        % updateResultsTab — Results tab
        %   Populates nowcast value and stat cards after model run
        function updateResultsTab(app)
            if isempty(app.OriginalResults) || isempty(app.Spec)
                return
            end

            try
                % ── Compute GDP nowcast value ─────────────────────────────────────
                y = computeGDPProjection(app, app.OriginalResults, app.Spec);

                % Most recent non-NaN value
                gdp_idx = find(strcmp('GDPC1', app.Spec.SeriesID), 1);
                if isempty(gdp_idx)
                    return
                end

                % Find last observed quarter from X_sm
                xSm = app.OriginalResults.X_sm(:, gdp_idx);
                lastObs = find(~isnan(xSm), 1, 'last');
                if isempty(lastObs)
                    lastObs = length(y);
                end

                % Store nowcast value
                app.NowcastValue = y(lastObs);

                % Format quarter string from time vector
                t = app.TimeOriginal;
                if ~isempty(t) && lastObs <= length(t)
                    [yr, mo, ~] = datevec(t(lastObs));
                    q = ceil(mo / 3);
                    app.NowcastQuarter = sprintf('%d:Q%d', yr, q);
                else
                    app.NowcastQuarter = 'Latest';
                end

                % ── Update gdp panel ─────────────────────────────────────────────
                % Format value with sign
                val = app.NowcastValue;
                if val >= 0
                    valStr = sprintf('+%.2f%%', val);
                else
                    valStr = sprintf('%.2f%%', val);
                end

                app.NowcastValueLabel.Text     = valStr;
                app.NowcastTitleLabel.Text     = ['GDP nowcast — ' app.NowcastQuarter];

                % Color: green if positive, red if negative
                if val >= 0
                    app.NowcastValueLabel.FontColor = [0.13 0.55 0.13];
                else
                    app.NowcastValueLabel.FontColor = [0.85 0.10 0.10];
                end

                % ── Stat cards — require old vintage, show placeholder ────────────
                app.PrevVintageLabel.Text = '--';
                app.RevisionLabel.Text    = '--';
                app.ReleaseLabel.Text     = '--';

                % ── News detail table — empty until old vintage wired ─────────────
                app.NewsTable.Data = cell(0, 6);

            catch ME
                uialert(app.UIFigure, ME.message, 'Results Tab Error');
            end
        end

        %------------------------------------------------------------------------------------------

        function updateForecastDetailsLog(app)
            if isempty(app.OriginalLog), return; end
            fullLog = [
                "===== ORIGINAL MODEL OUTPUT ====="
                splitlines(string(app.OriginalLog))
                ""
                "===== MODIFIED MODEL OUTPUT ====="
                splitlines(string(app.UpdatedLog))
                ];
            app.TextArea_4.Value = cellstr(fullLog);
        end
        %------------------------------------------------------------------------------------------

        %------------------------------------------------------------------------------------------
        % populateFiguresDropdowns
        %   Called after spec loads and after run — fills Fig1 and Fig3 dropdowns
        function populateFiguresDropdowns(app)
            if isempty(app.Spec)
                return
            end

            % All series for Fig1
            allIDs = app.Spec.SeriesID;
            app.Fig1SeriesDropdown.Items = allIDs;
            if ~isempty(allIDs)
                % Default to INDPRO if present, else first series
                if ismember('INDPRO', allIDs)
                    app.Fig1SeriesDropdown.Value = 'INDPRO';
                else
                    app.Fig1SeriesDropdown.Value = allIDs{1};
                end
            end

            % Non-GDP series for Fig3 top panel
            isGDP  = strcmp('GDPC1', app.Spec.SeriesID);
            nonGDP = app.Spec.SeriesID(~isGDP);
            app.Fig3SeriesDropDown.Items = nonGDP;
            if ~isempty(nonGDP)
                if ismember('PAYEMS', nonGDP)
                    app.Fig3SeriesDropDown.Value = 'PAYEMS';
                else
                    app.Fig3SeriesDropDown.Value = nonGDP{1};
                end
            end
        end

        %------------------------------------------------------------------------------------------
        % updateFig1 — Data tab
        %   Top axes: raw observed data (Z)
        %   Bottom axes: transformed/standardized data (X_sm)
        function updateFig1(app)
            if isempty(app.OriginalResults) || isempty(app.Spec)
                return
            end

            selectedID = app.Fig1SeriesDropdown.Value;
            idx = find(strcmp(selectedID, app.Spec.SeriesID), 1);
            if isempty(idx)
                return
            end

            t = app.TimeOriginal;
            freq = app.Spec.Frequency{idx};
            if strcmp(freq, 'q')
                fmt = 'QQ yyyy';
            else
                fmt = 'mmm yyyy';
            end

            % ── Top axes: raw data ───────────────────────────────────────────────
            cla(app.Fig1AxesRaw);
            if ~isempty(app.XData)
                rawSeries = app.XData(:, idx);
                nT = min(length(t), size(app.XData, 1));
                plot(app.Fig1AxesRaw, t(1:nT), rawSeries(1:nT), ...
                    'Color', [0.13 0.47 0.71], 'LineWidth', 1.4);
            end
            title(app.Fig1AxesRaw, ['Raw observed data — ' app.Spec.SeriesName{idx}]);
            ylabel(app.Fig1AxesRaw, app.Spec.Units{idx});
            xlabel(app.Fig1AxesRaw, '');
            grid(app.Fig1AxesRaw, 'on');
            datetick(app.Fig1AxesRaw, 'x', fmt, 'keeplimits');
            app.Fig1AxesRaw.XTickLabelRotation = 45;

            % ── Bottom axes: transformed data ────────────────────────────────────
            cla(app.Fig1AxesTransformed);
            xSm = app.OriginalResults.X_sm(:, idx);
            nT  = min(length(t), length(xSm));
            plot(app.Fig1AxesTransformed, t(1:nT), xSm(1:nT), ...
                'Color', [0.13 0.47 0.71], 'LineWidth', 1.4);
            title(app.Fig1AxesTransformed, 'Transformed data (Kalman smoothed)');
            ylabel(app.Fig1AxesTransformed, app.Spec.UnitsTransformed{idx});
            xlabel(app.Fig1AxesTransformed, '');
            grid(app.Fig1AxesTransformed, 'on');
            datetick(app.Fig1AxesTransformed, 'x', fmt, 'keeplimits');
            app.Fig1AxesTransformed.XTickLabelRotation = 45;
        end

        %------------------------------------------------------------------------------------------
        % updateFig2 — Common Factor tab
        %   All standardized smoothed series as dotted grey lines
        %   Global common factor overlaid as solid black line
        function updateFig2(app)
            if isempty(app.OriginalResults) || isempty(app.Spec)
                return
            end

            Res = app.OriginalResults;
            t   = app.TimeOriginal;
            nT  = min(length(t), size(Res.x_sm, 1));

            cla(app.Fig2Axes);
            hold(app.Fig2Axes, 'on');

            % All standardized smoothed series — dotted grey
            for i = 1:size(Res.x_sm, 2)
                plot(app.Fig2Axes, t(1:nT), Res.x_sm(1:nT, i), ...
                    'Color', [0.75 0.75 0.75], ...
                    'LineStyle', ':', ...
                    'LineWidth', 0.8, ...
                    'HandleVisibility', 'off');
            end

            % Global common factor — use INDPRO loading if available, else series 1
            refIdx = find(strcmp('INDPRO', app.Spec.SeriesID), 1);
            if isempty(refIdx)
                refIdx = 1;
            end
            commonFactor = Res.Z(1:nT, 1) .* Res.C(refIdx, 1);
            h = plot(app.Fig2Axes, t(1:nT), commonFactor, ...
                'k', 'LineWidth', 1.8, 'DisplayName', 'common factor');

            hold(app.Fig2Axes, 'off');
            legend(app.Fig2Axes, h, 'common factor', 'Location', 'northwest');
            legend(app.Fig2Axes, 'boxoff');
            title(app.Fig2Axes, 'Common Factor and Standardized Data');
            xlabel(app.Fig2Axes, '');
            ylabel(app.Fig2Axes, 'Standardized units');
            grid(app.Fig2Axes, 'on');
            datetick(app.Fig2Axes, 'x', 'yyyy', 'keeplimits');
            app.Fig2Axes.XTickLabelRotation = 45;
        end

        %------------------------------------------------------------------------------------------
        % updateFig3 — Projection tab
        %   Top panel: user-selected series, common factor projection vs actual
        %   Bottom panel: always GDPC1
        function updateFig3(app)
            if isempty(app.OriginalResults) || isempty(app.Spec)
                return
            end

            Res = app.OriginalResults;
            t   = app.TimeOriginal;
            nF  = size(app.Spec.Blocks, 2);

            % ── Top panel: selected series ────────────────────────────────────────
            selectedID = app.Fig3SeriesDropDown.Value;
            idx = find(strcmp(selectedID, app.Spec.SeriesID), 1);

            cla(app.Fig3AxesTop);
            if ~isempty(idx)
                nT = min(length(t), size(Res.Z, 1));

                % Common factor projection
                proj = Res.C(idx, 1:nF) * Res.Z(1:nT, 1:nF)' ...
                    .* Res.Wx(idx) + Res.Mx(idx);
                proj = proj(:);

                % Actual transformed data
                actual = Res.X_sm(1:nT, idx);
                t_obs  = ~isnan(actual);

                hold(app.Fig3AxesTop, 'on');
                plot(app.Fig3AxesTop, t(1:nT), proj, ...
                    'k', 'LineWidth', 1.4, 'DisplayName', 'common component');
                plot(app.Fig3AxesTop, t(t_obs), actual(t_obs), ...
                    'Color', [0.13 0.47 0.71], 'LineWidth', 1.2, ...
                    'DisplayName', 'data');
                hold(app.Fig3AxesTop, 'off');

                title(app.Fig3AxesTop, app.Spec.SeriesName{idx});
                ylabel(app.Fig3AxesTop, app.Spec.UnitsTransformed{idx});
                legend(app.Fig3AxesTop, 'show', 'Location', 'northwest');
                legend(app.Fig3AxesTop, 'boxoff');
                grid(app.Fig3AxesTop, 'on');
                datetick(app.Fig3AxesTop, 'x', 'yyyy', 'keeplimits');
                app.Fig3AxesTop.XTickLabelRotation = 45;
            end

            % ── Bottom panel: always GDPC1 ────────────────────────────────────────
            gdp_idx = find(strcmp('GDPC1', app.Spec.SeriesID), 1);

            cla(app.Fig3AxesBottom);
            if ~isempty(gdp_idx)
                nT = min(length(t), size(Res.Z, 1));

                proj = Res.C(gdp_idx, 1:nF) * Res.Z(1:nT, 1:nF)' ...
                    .* Res.Wx(gdp_idx) + Res.Mx(gdp_idx);
                proj = proj(:);

                actual = Res.X_sm(1:nT, gdp_idx);
                t_obs  = ~isnan(actual);

                hold(app.Fig3AxesBottom, 'on');
                plot(app.Fig3AxesBottom, t(1:nT), proj, ...
                    'k', 'LineWidth', 1.4, 'DisplayName', 'common component');
                plot(app.Fig3AxesBottom, t(t_obs), actual(t_obs), ...
                    'Color', [0.13 0.47 0.71], 'LineWidth', 1.2, ...
                    'DisplayName', 'data');
                hold(app.Fig3AxesBottom, 'off');

                title(app.Fig3AxesBottom, app.Spec.SeriesName{gdp_idx});
                ylabel(app.Fig3AxesBottom, app.Spec.UnitsTransformed{gdp_idx});
                legend(app.Fig3AxesBottom, 'show', 'Location', 'northwest');
                legend(app.Fig3AxesBottom, 'boxoff');
                grid(app.Fig3AxesBottom, 'on');
                datetick(app.Fig3AxesBottom, 'x', 'YYYY:QQ', 'keeplimits');
                app.Fig3AxesBottom.XTickLabelRotation = 45;
            end
        end

        %------------------------------------------------------------------------------------------
        % updateSpecCompGraphs — Spec Comparison tab
        %   Top: overlay of original vs modified GDP nowcast
        %   Bottom: difference bar chart
        function updateSpecCompGraphs(app)
            if isempty(app.OriginalResults) || isempty(app.UpdatedResults)
                return
            end

            try
                y1 = computeGDPProjection(app, app.OriginalResults, app.Spec);
                y2 = computeGDPProjection(app, app.UpdatedResults, app.ModifiedSpec);
                t  = app.TimeOriginal;
                n  = min(length(t), min(length(y1), length(y2)));

                % ── Overlay ───────────────────────────────────────────────────────
                cla(app.SpecCompOverlay);
                hold(app.SpecCompOverlay, 'on');

                plot(app.SpecCompOverlay, t(1:n), y1(1:n), ...
                    'Color', [0.13 0.47 0.71], 'LineWidth', 1.8, ...
                    'DisplayName', 'Original spec');
                plot(app.SpecCompOverlay, t(1:n), y2(1:n), ...
                    'Color', [0.84 0.15 0.16], 'LineWidth', 1.8, ...
                    'LineStyle', '--', 'DisplayName', 'Modified spec');

                hold(app.SpecCompOverlay, 'off');
                title(app.SpecCompOverlay, ...
                    'GDP nowcast: original vs modified specification');
                ylabel(app.SpecCompOverlay, 'Real GDP growth (ann. %)');
                xlabel(app.SpecCompOverlay, '');
                grid(app.SpecCompOverlay, 'on');
                legend(app.SpecCompOverlay, 'show', 'Location', 'northwest');
                legend(app.SpecCompOverlay, 'boxoff');
                app.SpecCompOverlay.XTickLabelRotation = 45;
                datetick(app.SpecCompOverlay, 'x', 'YYYY:QQ', 'keeplimits');

                % ── Difference ───────────────────────────────────────────────────
                cla(app.SpecCompDiff);
                bar(app.SpecCompDiff, t(1:n), y2(1:n) - y1(1:n), ...
                    'FaceColor', [0.50 0.50 0.50], 'EdgeColor', 'none');
                yline(app.SpecCompDiff, 0, 'k-', 'LineWidth', 0.5);
                title(app.SpecCompDiff, 'Difference (modified − original)');
                ylabel(app.SpecCompDiff, 'Δ ann. %');
                xlabel(app.SpecCompDiff, '');
                grid(app.SpecCompDiff, 'on');
                app.SpecCompDiff.XTickLabelRotation = 45;
                datetick(app.SpecCompDiff, 'x', 'YYYY:QQ', 'keeplimits');

            catch ME
                uialert(app.UIFigure, ME.message, 'Spec Comparison Error');
            end
        end

        % end of Custom METHODS
    end
    %------------------------------------------------------------------------------------------


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % ── Path setup ───────────────────────────────────────────────────────
            appDir = fileparts(which('app1.mlapp'));
            if isempty(appDir)
                appDir = pwd;
            end
            functionsDir = fullfile(appDir, 'functions');
            if ~isfolder(functionsDir)
                uialert(app.UIFigure, ...
                    ['Cannot find functions folder at ' functionsDir newline ...
                    'Run the app from its own directory.'], ...
                    'Path Error');
                return
            end
            addpath(functionsDir);

            % ── FilesPanel ────────────────────────────────────────────────────────
            app.FilesPanelLabel.Visible     = 'off';
            app.SpecStatusLabel.FontColor   = [0.5 0.5 0.5];
            app.DataStatusLabel.FontColor   = [0.5 0.5 0.5];

            % ── ConfigureRun ──────────────────────────────────────────────────────
            app.ConfigPanelLabel.Visible           = 'off';
            app.SeriesFilterLabel.Position         = [12 282 120 22];
            app.SeriesFilterNoteLabel.Position     = [136 282 560 22];
            app.SeriesFilterNoteLabel.Text         = '(uncheck to exclude from modified model)';

            % Checkbox panel — full width
            app.BlockCheckboxes  = matlab.ui.control.CheckBox.empty(1,0);
            app.SeriesCheckboxes = {};
            app.CheckboxScrollPanel.Position   = [12 58 716 218];
            app.CheckboxScrollPanel.Title      = '';
            app.CheckboxScrollPanel.BorderType = 'line';

            % Run model button styling
            app.RunModelButton.BackgroundColor = [0.49 0.18 0.56];
            app.RunModelButton.FontColor       = [1 1 1];

            % ── Forecast Details log styling ──────────────────────────────────────
            app.TextArea_4.BackgroundColor = [0.15 0.15 0.15];
            app.TextArea_4.FontColor       = [0.85 0.85 0.85];
            app.TextArea_4.FontName        = 'Courier New';
            app.TextArea_4.FontSize        = 11;


            % ── Figures tab layout ────────────────────────────────────────────────
            app.TabGroup2.Position = [0 0 778 568];

            % Fig1
            app.Fig1SeriesDropdownLabel.Text     = 'Series';
            app.Fig1SeriesDropdownLabel.Position = [10 500 55 22];
            app.Fig1SeriesDropdown.Position      = [70 500 250 22];
            app.Fig1AxesRaw.Position             = [10 265 740 225];
            app.Fig1AxesTransformed.Position     = [10 20  740 225];

            % Fig2
            app.Fig2Axes.Position                = [10 20  740 510];

            % Fig3
            app.DropDown2Label.Text              = 'Series';
            app.DropDown2Label.Position          = [10 500 55 22];
            app.Fig3SeriesDropDown.Position      = [70 500 250 22];
            app.Fig3AxesTop.Position             = [10 265 740 225];
            app.Fig3AxesBottom.Position          = [10 20  740 225];

            % SpecComp
            app.SpecCompOverlay.Position         = [10 155 740 375];
            app.SpecCompDiff.Position            = [10 20  740 115];

            % News
            app.NewsAxes.Position                = [10 20  740 510];
            title(app.NewsAxes, ...
                'News decomposition — requires old vintage data (not yet implemented)');
            xlabel(app.NewsAxes, '');
            ylabel(app.NewsAxes, '');

            % Tab titles
            app.Fig1Tab.Title     = 'Fig 1: Data';
            app.Fig2Tab.Title     = 'Fig 2: Common Factor';
            app.Fig3Tab.Title     = 'Fig 3: Projection';

            % ── Results tab ───────────────────────────────────────────────────────
            app.SummaryTable.Title = 'Results';

        end

        % Button pushed function: SpecFileButton
        function LoadSpecFileButtonPushed(app, event)
            loadSpecFile(app);
        end

        % Button pushed function: RunSummaryTableButton
        function RunSummaryTableButtonPushed(app, event)
            runModel(app);
            displayResultsInTextArea(app);
        end

        % Button pushed function: LoadDataFileButton
        function LoadDataFileButtonPushed(app, event)
            loadDataFile(app);
        end

        % Button pushed function: RunModelButton
        function RunModelButtonPushed(app, event)
            runModel(app);
            updateResultsTab(app);
            updateForecastDetailsLog(app);
            populateFiguresDropdowns(app);
            updateSpecCompGraphs(app);


            updateFig1(app);
            updateFig2(app);
            updateFig3(app);
        end

        % Button pushed function: SelectAllButton
        function SelectAllButtonPushed(app, event)
            for b = 1:numel(app.BlockCheckboxes)
                app.BlockCheckboxes(b).Value     = 1;
                app.BlockCheckboxes(b).FontColor = [0.24 0.20 0.54];
                sCBs = app.SeriesCheckboxes{b};
                for i = 1:numel(sCBs)
                    sCBs(i).Value = 1;
                end
            end
        end

        % Button pushed function: DeselectAllButton
        function DeselectAllButtonPushed(app, event)
            for b = 1:numel(app.BlockCheckboxes)
                app.BlockCheckboxes(b).Value     = 0;
                app.BlockCheckboxes(b).FontColor = [0.24 0.20 0.54];
                sCBs = app.SeriesCheckboxes{b};
                for i = 1:numel(sCBs)
                    sCBs(i).Value = 0;
                end
            end
        end

        % Value changed function: Fig1SeriesDropdown
        function Fig1SeriesDropDownChanged(app, event)
            updateFig1(app);
        end

        % Value changed function: Fig3SeriesDropDown
        function Fig3SeriesDropdownChanged(app, event)
            updateFig3(app);
        end

        % Button pushed function: SampleSpecButton
        function SampleSpecButtonPushed(app, event)
            % Load the bundled sample spec file without a file dialog
            sampleSpec = fullfile(pwd, 'Spec_US_expanded.xlsx');
            if ~isfile(sampleSpec)
                uialert(app.UIFigure, ['File not found in: ' pwd], 'File Not Found');
                return
            end
            try
                app.Spec = load_spec(sampleSpec);
                app.SpecFilePath = sampleSpec;
                populateFilterPanel(app);
                populateFiguresDropdowns(app);
                drawnow;
                [~, fname, ext] = fileparts(sampleSpec);
                app.SpecStatusLabel.Text      = ['✓ ' fname ext ' (sample)'];
                app.SpecStatusLabel.FontColor = [0.13 0.55 0.13];   % green
                app.SpecStatusLabel.WordWrap = 'off';
            catch ME
                uialert(app.UIFigure, ME.message, 'Error loading sample spec');
            end

        end

        % Button pushed function: SampleDataButton
        function SampleDataButtonPushed(app, event)
            % Load the bundled sample data file without a file dialog
            sampleData = fullfile(pwd, 'Data', 'Data_US_matching_expanded_spec.xlsx');
            if ~isfile(sampleData)
                uialert(app.UIFigure, ['File not found in: ' pwd], 'File Not Found');
                return
            end
            try
                app.DataFilePath = sampleData;
                [~, fname, ext] = fileparts(sampleData);
                app.DataStatusLabel.Text      = ['✓ ' fname ext ' (sample)'];
                app.DataStatusLabel.FontColor = [0.13 0.55 0.13];   % green
                app.DataStatusLabel.WordWrap = 'off';

            catch ME
                uialert(app.UIFigure, ME.message, 'Error loading sample data');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.ConfigureRun = uipanel(app.MainTab);
            app.ConfigureRun.Title = 'Configure Run';
            app.RunModelButton = uibutton(app.ConfigureRun, 'push');
            app.RunModelButton.ButtonPushedFcn = createCallbackFcn(app, @RunModelButtonPushed, true);
            app.RunModelButton.Text = 'Run Model';
            app.ConfigPanelLabel = uilabel(app.ConfigureRun);
            app.ConfigPanelLabel.Text = '2- Configure run';
            app.SeriesFilterLabel = uilabel(app.ConfigureRun);
            app.SeriesFilterLabel.Text = 'Include Series';
            app.SeriesFilterNoteLabel = uilabel(app.ConfigureRun);
            app.SeriesFilterNoteLabel.Text = 'populated from spec file';
            app.CheckboxScrollPanel = uipanel(app.ConfigureRun);
            app.SelectAllButton = uibutton(app.ConfigureRun, 'push');
            app.SelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButtonPushed, true);
            app.SelectAllButton.Text = 'Select All';
            app.DeselectAllButton = uibutton(app.ConfigureRun, 'push');
            app.DeselectAllButton.ButtonPushedFcn = createCallbackFcn(app, @DeselectAllButtonPushed, true);
            app.DeselectAllButton.Text = 'Deselect All';
            app.FilterScrollPanel = uipanel(app.ConfigureRun);
            app.FilesPanel = uipanel(app.MainTab);
            app.FilesPanel.Title = 'Load Files';
            app.DataStatusLabel = uilabel(app.FilesPanel);
            app.DataStatusLabel.Text = 'Not loaded';
            app.SpecStatusLabel = uilabel(app.FilesPanel);
            app.SpecStatusLabel.Text = 'Not loaded';
            app.FilesPanelLabel = uilabel(app.FilesPanel);
            app.FilesPanelLabel.Text = '1- Load Files';
            app.SpecFileButton = uibutton(app.FilesPanel, 'push');
            app.SpecFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSpecFileButtonPushed, true);
            app.SpecFileButton.Text = 'Load Spec File';
            app.LoadDataFileButton = uibutton(app.FilesPanel, 'push');
            app.LoadDataFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataFileButtonPushed, true);
            app.LoadDataFileButton.Text = 'Load Data File';
            app.SampleSpecButton = uibutton(app.FilesPanel, 'push');
            app.SampleSpecButton.ButtonPushedFcn = createCallbackFcn(app, @SampleSpecButtonPushed, true);
            app.SampleSpecButton.Text = 'Use sample';
            app.SampleDataButton = uibutton(app.FilesPanel, 'push');
            app.SampleDataButton.ButtonPushedFcn = createCallbackFcn(app, @SampleDataButtonPushed, true);
            app.SampleDataButton.Text = 'Use sample';
            app.GDPNowcastingAppLabel = uilabel(app.MainTab);
            app.GDPNowcastingAppLabel.Text = 'GDP Nowcasting App';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.PrevVintagePanel = uipanel(app.ResultsTab);
            app.PrevVintageLabel = uilabel(app.PrevVintagePanel);
            app.PrevVintageLabel.Text = '--';
            app.PrevVintageDateLabel = uilabel(app.PrevVintagePanel);
            app.PrevVintageDateLabel.Text = 'Previous vintage';
            app.RevisionPanel = uipanel(app.ResultsTab);
            app.RevisionLabel = uilabel(app.RevisionPanel);
            app.RevisionLabel.Text = '--';
            app.RevisionSubLabel = uilabel(app.RevisionPanel);
            app.RevisionSubLabel.Text = 'from data revisions';
            app.ReleasePanel = uipanel(app.ResultsTab);
            app.ReleaseLabel = uilabel(app.ReleasePanel);
            app.ReleaseLabel.Text = '--';
            app.ReleaseSubLabel = uilabel(app.ReleasePanel);
            app.ReleaseSubLabel.Text = 'from new data releases';
            app.NewsTable = uitable(app.ResultsTab);
            app.NewsTable.ColumnName = {'Series ID'; 'Series Name'; 'Forecast'; 'Actual'; 'Weight'; 'Impact (%)'};
            app.NewsTable.RowName = {};
            app.NowcastPanel = uipanel(app.ResultsTab);
            app.NowcastTitleLabel = uilabel(app.NowcastPanel);
            app.NowcastTitleLabel.Text = 'GDP nowcast -- --';
            app.NowcastValueLabel = uilabel(app.NowcastPanel);
            app.NowcastValueLabel.Text = '--';
            app.NowcastUnitsLabel = uilabel(app.NowcastPanel);
            app.NowcastUnitsLabel.Text = 'QoQ% annualized rate';
            app.NewsTableLabel = uilabel(app.ResultsTab);
            app.NewsTableLabel.Text = 'NEWS DETAIL TABLE';
            app.ExportExcelButton = uibutton(app.ResultsTab, 'push');
            app.ExportExcelButton.Text = 'Export to Excel';
            app.FiguresTab = uitab(app.TabGroup);
            app.FiguresTab.Title = 'Figures';
            app.TabGroup2 = uitabgroup(app.FiguresTab);
            app.Fig1Tab = uitab(app.TabGroup2);
            app.Fig1Tab.Title = 'Fig 1';
            app.Fig1AxesRaw = uiaxes(app.Fig1Tab);
            title(app.Fig1AxesRaw, 'Title')
            xlabel(app.Fig1AxesRaw, 'X')
            ylabel(app.Fig1AxesRaw, 'Y')
            zlabel(app.Fig1AxesRaw, 'Z')
            app.Fig1AxesTransformed = uiaxes(app.Fig1Tab);
            title(app.Fig1AxesTransformed, 'Title')
            xlabel(app.Fig1AxesTransformed, 'X')
            ylabel(app.Fig1AxesTransformed, 'Y')
            zlabel(app.Fig1AxesTransformed, 'Z')
            app.Fig1SeriesDropdownLabel = uilabel(app.Fig1Tab);
            app.Fig1SeriesDropdownLabel.Text = 'Drop Down';
            app.Fig1SeriesDropdown = uidropdown(app.Fig1Tab);
            app.Fig1SeriesDropdown.ValueChangedFcn = createCallbackFcn(app, @Fig1SeriesDropDownChanged, true);
            app.Fig2Tab = uitab(app.TabGroup2);
            app.Fig2Tab.Title = 'Fig 2';
            app.Fig2Axes = uiaxes(app.Fig2Tab);
            title(app.Fig2Axes, 'Title')
            xlabel(app.Fig2Axes, 'X')
            ylabel(app.Fig2Axes, 'Y')
            zlabel(app.Fig2Axes, 'Z')
            app.Fig3Tab = uitab(app.TabGroup2);
            app.Fig3Tab.Title = 'Fig 3';
            app.Fig3AxesTop = uiaxes(app.Fig3Tab);
            title(app.Fig3AxesTop, 'Title')
            xlabel(app.Fig3AxesTop, 'X')
            ylabel(app.Fig3AxesTop, 'Y')
            zlabel(app.Fig3AxesTop, 'Z')
            app.Fig3AxesBottom = uiaxes(app.Fig3Tab);
            title(app.Fig3AxesBottom, 'Title')
            xlabel(app.Fig3AxesBottom, 'X')
            ylabel(app.Fig3AxesBottom, 'Y')
            zlabel(app.Fig3AxesBottom, 'Z')
            app.DropDown2Label = uilabel(app.Fig3Tab);
            app.DropDown2Label.Text = 'Drop Down2';
            app.Fig3SeriesDropDown = uidropdown(app.Fig3Tab);
            app.Fig3SeriesDropDown.ValueChangedFcn = createCallbackFcn(app, @Fig3SeriesDropdownChanged, true);
            app.SpecCompTab = uitab(app.TabGroup2);
            app.SpecCompTab.Title = 'Spec Comparison';
            app.SpecCompOverlay = uiaxes(app.SpecCompTab);
            title(app.SpecCompOverlay, 'Title')
            xlabel(app.SpecCompOverlay, 'X')
            ylabel(app.SpecCompOverlay, 'Y')
            zlabel(app.SpecCompOverlay, 'Z')
            app.SpecCompDiff = uiaxes(app.SpecCompTab);
            title(app.SpecCompDiff, 'Title')
            xlabel(app.SpecCompDiff, 'X')
            ylabel(app.SpecCompDiff, 'Y')
            zlabel(app.SpecCompDiff, 'Z')
            app.NewsTab = uitab(app.TabGroup2);
            app.NewsTab.Title = 'News';
            app.NewsAxes = uiaxes(app.NewsTab);
            title(app.NewsAxes, 'Title')
            xlabel(app.NewsAxes, 'X')
            ylabel(app.NewsAxes, 'Y')
            zlabel(app.NewsAxes, 'Z')
            app.AICopilotTab = uitab(app.TabGroup);
            app.AICopilotTab.Title = 'AI Copilot';
            app.SummaryTable = uitab(app.TabGroup);
            app.SummaryTable.Title = 'Summary Table';
            app.RunSummaryTableButton = uibutton(app.SummaryTable, 'push');
            app.RunSummaryTableButton.ButtonPushedFcn = createCallbackFcn(app, @RunSummaryTableButtonPushed, true);
            app.RunSummaryTableButton.Text = 'Run: Summary Table';
            app.ShortSummaryLabel = uilabel(app.SummaryTable);
            app.ShortSummaryLabel.Text = 'Short Summary';
            app.FullLogLabel = uilabel(app.SummaryTable);
            app.FullLogLabel.Text = 'Full Log';
            app.TextArea = uitextarea(app.SummaryTable);
            app.TextArea_4 = uitextarea(app.SummaryTable);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end